classdef CoordinatePlot_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1        matlab.ui.Figure
        uitoolbar1     matlab.ui.container.Toolbar
        uipushtool1    matlab.ui.container.toolbar.PushTool
        uitoggletool1  matlab.ui.container.toolbar.ToggleTool
        uitoggletool2  matlab.ui.container.toolbar.ToggleTool
        uitoggletool3  matlab.ui.container.toolbar.ToggleTool
        uitoggletool4  matlab.ui.container.toolbar.ToggleTool
        uitoggletool5  matlab.ui.container.toolbar.ToggleTool
        uitoggletool6  matlab.ui.container.toolbar.ToggleTool
        text7          matlab.ui.control.Label
        xlabel_bottom  matlab.ui.control.Label
        xlabel_middle  matlab.ui.control.Label
        xlabel_top     matlab.ui.control.Label
        edit_comm      matlab.ui.control.TextArea
        pushbutton1    matlab.ui.control.Button
        edit_YlimV2    matlab.ui.control.EditField
        text3          matlab.ui.control.Label
        edit_YlimV1    matlab.ui.control.EditField
        edit_YlimHz2   matlab.ui.control.EditField
        text2          matlab.ui.control.Label
        edit_YlimHz1   matlab.ui.control.EditField
        text1          matlab.ui.control.Label
        axes3          matlab.ui.control.UIAxes
        axes2          matlab.ui.control.UIAxes
        axes1          matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.uipushtool1.ClickedCallback = 'filemenufcn(gcbf,''FileSave'')';
            app.uitoggletool1.ClickedCallback = 'putdowntext(''zoomin'',gcbo)';
            app.uitoggletool2.ClickedCallback = 'putdowntext(''zoomout'',gcbo)';
            app.uitoggletool3.ClickedCallback = 'putdowntext(''pan'',gcbo)';
            app.uitoggletool4.ClickedCallback = 'putdowntext(''datatip'',gcbo)';
            app.uitoggletool5.ClickedCallback = 'insertmenufcn(gcbf,''Legend'')';
            app.uitoggletool6.ClickedCallback = 'insertmenufcn(gcbf,''Colorbar'')';
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function CoordinatePlot_OpeningFcn(app, varargin)
            % --- Executes just before CoordinatePlot is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to CoordinatePlot (see VARARGIN)
            
            % using vline.m or hline.m (c) 2001, Brandon Kuczenski
            
            % epochs = varargin{1};       % vector, 1:#epochs
            dN = varargin{2};
            dE = varargin{3};
            dh = varargin{4};
            time = varargin{5};         % time [sow]
            strXAxis_epochs = varargin{6};
            seconds = varargin{7};      % time [s] from beginning of processing
            resets = varargin{8};       % time [s] of resets from beginning of processing
            station_date = varargin{9};	% string
            floatfix = varargin{10};  	% string
            
            % Choose default command line output for CoordinatePlot
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % find limits for plot
            lim1 = max([ abs(min([dN,dE])); max([dN,dE]) ]);
            lim2 = max([ abs(min(dh));      max(max(dh)) ]);
            default_ylim_hor =  [-lim1,lim1];
            default_ylim_vert = [-lim2,lim2];
            if isequal(default_ylim_hor, [0,0])
                default_ylim_hor =  [-1,1];
            end
            if isequal(default_ylim_vert, [0,0])
                default_ylim_vert = [-1,1];
            end
            
            % create ticks for time
            duration = seconds(end)-seconds(1); 	% processed time-span in [s]
            sec_rounded = round(10*seconds)/10;
            if duration < 300                   % less than 5min processed
                take_time = (mod(sec_rounded,30) == 0);         % all 30sec
            elseif duration < 1200              % less than 20min processed
                take_time = (mod(sec_rounded,180) == 0);    	% all 3min
            elseif duration < 3600             	% less than 1h processed
                take_time = (mod(sec_rounded,600) == 0);    	% all 10min
            elseif duration < 14400             % less than 4h processed
                take_time = (mod(sec_rounded,1800) == 0);       % all 30min
            elseif duration < 28800             % less than 8h processed
                take_time = (mod(sec_rounded,3600) == 0);       % all 60min
            else                                % more than 8h processed
                take_time = (mod(sec_rounded,7200) == 0);       % all 2h
            end
            idx = 1:numel(take_time);
            idx = idx(take_time);       % get indices to easier remove duplicates
            vec_time = time(idx);       % get time in sow
            [vec_time, idx_] = unique(vec_time);	% remove potential identical times
            idx = idx(idx_);
            ticks_time = sow2hhmm(vec_time); 	% calculate ticks to time
            vec_time   = seconds(idx);          % get epoch of tick
            
            % create ticks for epochs
            total_eps = numel(dE);              % number of processed epochs
            epochs = 1:total_eps;
            if total_eps < 150
                take_eps = (mod(epochs,   10) == 0);
            elseif total_eps < 250
                take_eps = (mod(epochs,   25) == 0);
            elseif total_eps < 300
                take_eps = (mod(epochs,   50) == 0);
            elseif total_eps < 1000
                take_eps = (mod(epochs,  100) == 0);
            elseif total_eps < 3000
                take_eps = (mod(epochs,  250) == 0);
            elseif total_eps < 10000
                take_eps = (mod(epochs, 1000) == 0);
            elseif total_eps < 25000
                take_eps = (mod(epochs, 2500) == 0);
            else                                % more than 25000 epochs processed
                take_eps = (mod(epochs,10000) == 0);
            end
            ticks_eps = epochs(take_eps);
            vec_eps   = seconds(take_eps);             	% get epoch of tick
            
            % write station and date
            set(handles.text7, 'String', [station_date ', ' floatfix]);
            % set values into the four edit boxes
            set(handles.edit_YlimHz1,'String', '1');
            set(handles.edit_YlimHz2,'String',  '-1');
            set(handles.edit_YlimV1,'String', '2');
            set(handles.edit_YlimV2,'String',  '-2');
            
            none = (dN==0 & dE==0 & dh==0);         % indices of epochs without solution
            lgth_sol = length(dN);
            seconds = seconds(1:lgth_sol);          % last epochs of processing delivered no solution
            
            % --- Plot dN with time of day on x-axis
            axes(handles.axes1);
            cla
            hold off
            plot(app.axes1, seconds,dN,'color',[0.82 0 0],'linewidth',1);
            hold on
            plot(app.axes1, seconds(none),dN(none),'.', 'color', 'k');
            ylabel(app.axes1, 'dN [m]')
            xlim(app.axes1, [seconds(1),seconds(end)]);
            ylim(app.axes1, default_ylim_hor);         % set the y-limits to the max-min-values of the data
            xticks(vec_time)
            xticklabels(cellstr(ticks_time))
            Grid_Xoff_Yon();
            set(handles.xlabel_top, 'String', ['Time' strXAxis_epochs(4:end)]);
            if ~isempty(resets); vline(resets, 'k:'); end	% plot vertical lines for resets
            
            % --- Plot dE  with number of epoch on x-axis
            axes(app.axes2);
            cla
            hold off
            plot(app.axes2, seconds,dE,'color',[0 0 0.82],'linewidth',1);
            hold on
            plot(app.axes2, seconds(none),dE(none),'.', 'color','k');
            ylabel(app.axes2,'dE [m]')
            xlim(app.axes2,[seconds(1),seconds(end)]);
            ylim(app.axes2,default_ylim_hor);         % set the y-limits to the max-min-values of the data
            xticks(vec_eps)
            xticklabels(sprintfc('%d', ticks_eps))
            Grid_Xoff_Yon();
            set(handles.xlabel_middle, 'String', 'Epochs');
            if ~isempty(resets); vline(resets, 'k:'); end	% plot vertical lines for resets
            
            % --- Plot dh with seconds of processing on x-axis---
            axes(app.axes3);
            cla
            hold off
            plot(app.axes3, seconds,dh,'color',[0 0.82 0],'linewidth',1);
            hold on
            plot(app.axes3, seconds(none),dh(none),'.', 'color','k');
            ylabel(app.axes3, 'dh [m]')
            xlim(app.axes3, [seconds(1),seconds(end)]);
            ylim(app.axes3, default_ylim_vert);
            Grid_Xoff_Yon();
            set(handles.xlabel_bottom, 'String', 'Seconds');
            if ~isempty(resets); vline(resets, 'k:'); end	% plot vertical lines for resets
            
            linkaxes([app.axes1, app.axes2, app.axes3],'x')
        end

        % Value changed function: edit_YlimHz1
        function edit_YlimHz1_Callback(app, event)

            % set second limit to the negative first limit
            value = str2double(app.edit_YlimHz1.Value);
            app.edit_YlimHz2.Value = num2str(-value);
            % update y-limits
            pushbutton1_Callback(app, event)
        end

        % Value changed function: edit_YlimHz2
        function edit_YlimHz2_Callback(app, event)
        
            pushbutton1_Callback(app, event)
        end

        % Value changed function: edit_YlimV1
        function edit_YlimV1_Callback(app, event)

            % set second limit to the negative first limit
            value = str2double(app.edit_YlimV1.Value);
            app.edit_YlimV2.Value = num2str(-value);
            % update y-limits
            pushbutton1_Callback(app, event)
        end

        % Value changed function: edit_YlimV2
        function edit_YlimV2_Callback(app, event)
          
            pushbutton1_Callback(app, event)
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % --- Executes on button press in pushbutton1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % get y-limits from the four edit boxes
            try
                hzlim = [sscanf(get(handles.edit_YlimHz2,'String'), '%f'), ...
                    sscanf(get(handles.edit_YlimHz1,'String'), '%f')];
            catch
                hzlim = [-1 1];
            end
            try
                vlim = [ sscanf(get(handles.edit_YlimV2,'String'),  '%f'), ...
                    sscanf(get(handles.edit_YlimV1,'String'),  '%f')];
            catch
                vlim = [-2 2];
            end
            
            % Set y-limits of the three plot-windows
            ylim(handles.axes1, hzlim);
            ylim(handles.axes1_1, hzlim);
            ylim(handles.axes1_2, vlim);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Coordinate Plot';
            app.figure1.Tag = 'figure1';
            app.uitoolbar1 = uitoolbar(app.figure1);
            app.uitoolbar1.Tag = 'uitoolbar1';
            app.uipushtool1 = uipushtool(app.uitoolbar1);
            app.uipushtool1.Tag = 'uipushtool1';
            app.uipushtool1.Tooltip = 'Save Figure';
            app.uipushtool1.Icon = 'uipushtool1_image.png';
            app.uitoggletool1 = uitoggletool(app.uitoolbar1);
            app.uitoggletool1.Tag = 'uitoggletool1';
            app.uitoggletool1.Tooltip = 'Zoom In';
            app.uitoggletool1.Icon = 'uitoggletool1_image.png';
            app.uitoggletool2 = uitoggletool(app.uitoolbar1);
            app.uitoggletool2.Tag = 'uitoggletool2';
            app.uitoggletool2.Tooltip = 'Zoom Out';
            app.uitoggletool2.Icon = 'uitoggletool2_image.png';
            app.uitoggletool3 = uitoggletool(app.uitoolbar1);
            app.uitoggletool3.Tag = 'uitoggletool3';
            app.uitoggletool3.Tooltip = 'Pan';
            app.uitoggletool3.Icon = 'uitoggletool3_image.png';
            app.uitoggletool4 = uitoggletool(app.uitoolbar1);
            app.uitoggletool4.Tag = 'uitoggletool4';
            app.uitoggletool4.Tooltip = 'Data Cursor';
            app.uitoggletool4.Icon = 'uitoggletool4_image.png';
            app.uitoggletool5 = uitoggletool(app.uitoolbar1);
            app.uitoggletool5.Tag = 'uitoggletool5';
            app.uitoggletool5.Tooltip = 'Insert Legend';
            app.uitoggletool5.Icon = 'uitoggletool5_image.png';
            app.uitoggletool6 = uitoggletool(app.uitoolbar1);
            app.uitoggletool6.Tag = 'uitoggletool6';
            app.uitoggletool6.Tooltip = 'Insert Colorbar';
            app.uitoggletool6.Icon = 'uitoggletool6_image.png';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.XLim = [0 99];
            app.axes1.YLim = [-1 1];
            app.axes1.XTick = [0 30 60 90];
            app.axes1.XTickLabel = {'17:11'; '17:11'; '17:12'; '17:12'};
            app.axes1.Tag = 'axes1';
            colormap(app.axes1, 'parula')
            app.axes2 = uiaxes(app.figure1);
            app.axes2.XLim = [0 99];
            app.axes2.YLim = [-1 1];
            app.axes2.XTick = [9 19 29 39 49 59 69 79 89 99];
            app.axes2.XTickLabel = {'10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'; '100'};
            app.axes2.Tag = 'axes1_1';
            colormap(app.axes2, 'parula')
            app.axes3 = uiaxes(app.figure1);
            app.axes3.XLim = [0 99];
            app.axes3.YLim = [-1 1];
            app.axes3.Tag = 'axes1_2';
            colormap(app.axes3, 'parula')
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Y-Limits:';
            app.edit_YlimHz1 = uieditfield(app.figure1, 'text');
            app.edit_YlimHz1.ValueChangedFcn = createCallbackFcn(app, @edit_YlimHz1_Callback, true);
            app.edit_YlimHz1.Tag = 'edit_YlimHz1';
            app.edit_YlimHz1.Value = '1';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Y-Limits (dh):';
            app.edit_YlimHz2 = uieditfield(app.figure1, 'text');
            app.edit_YlimHz2.ValueChangedFcn = createCallbackFcn(app, @edit_YlimHz2_Callback, true);
            app.edit_YlimHz2.Tag = 'edit_YlimHz2';
            app.edit_YlimHz2.Value = '-1';
            app.edit_YlimV1 = uieditfield(app.figure1, 'text');
            app.edit_YlimV1.ValueChangedFcn = createCallbackFcn(app, @edit_YlimV1_Callback, true);
            app.edit_YlimV1.Tag = 'edit_YlimV1';
            app.edit_YlimV1.Value = '2';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'UTM-Coordinates over Time:';
            app.edit_YlimV2 = uieditfield(app.figure1, 'text');
            app.edit_YlimV2.ValueChangedFcn = createCallbackFcn(app, @edit_YlimV2_Callback, true);
            app.edit_YlimV2.Tag = 'edit_YlimV2';
            app.edit_YlimV2.Value = '-2';
            app.pushbutton1 = uibutton(app.figure1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'Update';
            app.edit_comm = uitextarea(app.figure1);
            app.edit_comm.Tag = 'edit_comm';
            app.edit_comm.Value = {'Place for comments'};
            app.xlabel_top = uilabel(app.figure1);
            app.xlabel_top.Tag = 'xlabel_top';
            app.xlabel_top.Text = 'Time, 1st Epoch: 17h:10m:55s';
            app.xlabel_middle = uilabel(app.figure1);
            app.xlabel_middle.Tag = 'xlabel_middle';
            app.xlabel_middle.Text = 'Epochs';
            app.xlabel_bottom = uilabel(app.figure1);
            app.xlabel_bottom.Tag = 'xlabel_bottom';
            app.xlabel_bottom.Text = 'Seconds';
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'GOOG 2020/249, float';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CoordinatePlot_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)CoordinatePlot_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end